classdef reducer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        MainTab                matlab.ui.container.Tab
        VFREditField           matlab.ui.control.NumericEditField
        VFRm3hrEditFieldLabel  matlab.ui.control.Label
        SummaryTextArea        matlab.ui.control.TextArea
        SummaryTextAreaLabel   matlab.ui.control.Label
        TypeDropDown           matlab.ui.control.DropDown
        TypeDropDownLabel      matlab.ui.control.Label
        ReducerDropDown        matlab.ui.control.DropDown
        ReducerDropDownLabel   matlab.ui.control.Label
        Image                  matlab.ui.control.Image
        RunButton              matlab.ui.control.Button
        Hyperlink              matlab.ui.control.Hyperlink
        UIAxes                 matlab.ui.control.UIAxes
        DataTab                matlab.ui.container.Tab
        UITable                matlab.ui.control.Table
    end

    
    properties (Access = public)
         % Reducer variables
         VFRvalue
         Reducer
         d_one
         d_two
         typeValue
         database
         index
    end
    
    methods (Access = public)
        
        function Results(app)

            % INPUTS
            t = app.database;
            ReducerStd = table2array(t(:,"Reducer_std"));
            d1 = table2array(t(:,"d2"));
            d2 = table2array(t(:,"d1"));
            L = table2array(t(:,"Length_L"));
            rho = 1000; % kg/m^3
            g = 9.81; % m/s^2

            
            %CALCULATION
            beta = d1./d2;
            theta = (((1./tan(((d2./2)-(d1./2))./L))*2)*pi/180);
            theta_deg = rad2deg(theta);
            K1_c = 0.8*(sin(theta./2)).*(1-(beta.^2));
            K1_e = 2.6*(sin(theta./2)).*((1-(beta.^2))).^2;
            
            vfr = app.VFREditField.Value;

            %Condition for Contraction and Enlargement
    
            if strcmp(num2str(app.typeValue), "Contraction")
                K = K1_c./(beta.^4);
                Area = pi*10e-7*d2.^2/4;
                vel = vfr./Area./3600;
                hL = K.*(vel.^2)/(2*g);
                dp = hL.*g*rho;

                %for graph
                k1 = K(app.index);
                graph_vel = 0:1:500;
                hL1 = k1*(graph_vel.^2)/(2*g);
                dp1 = hL1.*g*rho;
                plot(app.UIAxes,dp1);

                %for text area
                d2_t = d2(app.index);
                A_t = pi*10e-7*d2_t^2/4;
                vel_t = vfr/A_t/3600;
                hL_t = k1*(vel_t^2)/(2*g);
                dp_t = hL_t*g*rho;
                app.SummaryTextArea.Value = sprintf("The value of pressure drop is %f Pa for velocity %f m/s",dp_t, vel_t);

            elseif strcmp(num2str(app.typeValue), "Enlargement")
                K = K1_e./(beta.^4);
                Area = pi*10e-7*d1.^2/4;
                vel = vfr./Area./3600;
                hL = K.*(vel.^2)/(2*g);
                dp = hL.*g*rho;
                
                %for graph
                k1 = K(app.index);
                graph_vel = 0:1:500;
                hL1 = k1*(graph_vel.^2)/(2*g);
                dp1 = hL1.*g*rho;
                plot(app.UIAxes,dp1);

                %for text area
                d2_t = d2(app.index);
                A_t = pi*10e-7*d2_t^2/4;
                vel_t = vfr/A_t/3600;
                hL_t = k1*(vel_t^2)/(2*g);
                dp_t = hL_t*g*rho;
                app.SummaryTextArea.Value = sprintf("The value of pressure drop is %f Pa for velocity %f m/s",dp_t, vel_t);

            else 
                app.SummaryTextArea.Value = sprintf("Warning! Please select the reducer type.");
                K = K1_e.*0;
                Area = 0*d1.^2/4;
                vel = 0.*Area./3600;
                hL = 0.*(vel.^2)/(2*g);
                dp = hL.*0*rho;

            end

            % Convert to table
            b = array2table(beta);
            K_value = array2table(K);
            Areaa = array2table(Area);
            head_loss = array2table(hL);
            press_drop = array2table(dp);
            
            %OUTPUT
            app.UITable.Data = [t b K_value Areaa head_loss press_drop];
            app.UITable.ColumnName{5} = 'd2/d1';
            app.UITable.ColumnName{6} = 'K Value';
            app.UITable.ColumnName{7} = 'Area (m^2)';
            app.UITable.ColumnName{8} = 'Head Loss (m)';
            app.UITable.ColumnName{9} = 'Pressure Drop (Pa)';


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            filename = 'data.xlsx';
            filepath = fullfile(filename);
            app.database = readtable(filepath,"Sheet",1);
            reducerStd = table2array(app.database(:,"Reducer_std"));

            app.ReducerDropDown.Items = reducerStd;
            
            app.UITable.Data = app.database;
            app.UITable.ColumnName = app.database.Properties.VariableNames;
        end

        % Value changed function: ReducerDropDown
        function ReducerDropDownValueChanged(app, event)
            selectedValue = app.ReducerDropDown.Value;
            selectedIndex = find(strcmp(app.ReducerDropDown.Items, selectedValue));
            app.index = selectedIndex;
            
        end

        % Callback function: Hyperlink
        function HyperlinkClicked(app, event)
            winopen('Reducer_Document.docx');
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
            Results(app);

        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            value = app.TypeDropDown.Value;
            app.typeValue = value;
        end

        % Value changed function: VFREditField
        function VFREditFieldValueChanged(app, event)
            app.VFRvalue = app.VFREditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.UIAxes = uiaxes(app.MainTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Velocity (m/s)')
            ylabel(app.UIAxes, 'Pressure drop (Pa)')
            zlabel(app.UIAxes, 'Z')
            app.Hyperlink = uihyperlink(app.MainTab);
            app.Hyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @HyperlinkClicked, true);
            app.Hyperlink.Text = 'Help?';
            app.RunButton = uibutton(app.MainTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Image = uiimage(app.MainTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'image.png');
            app.ReducerDropDownLabel = uilabel(app.MainTab);
            app.ReducerDropDownLabel.Text = 'Reducer';
            app.ReducerDropDown = uidropdown(app.MainTab);
            app.ReducerDropDown.Items = {' '};
            app.ReducerDropDown.ValueChangedFcn = createCallbackFcn(app, @ReducerDropDownValueChanged, true);
            app.ReducerDropDown.Value = ' ';
            app.TypeDropDownLabel = uilabel(app.MainTab);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.MainTab);
            app.TypeDropDown.Items = {' ', 'Contraction', 'Enlargement'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = ' ';
            app.SummaryTextAreaLabel = uilabel(app.MainTab);
            app.SummaryTextAreaLabel.Text = 'Summary';
            app.SummaryTextArea = uitextarea(app.MainTab);
            app.VFRm3hrEditFieldLabel = uilabel(app.MainTab);
            app.VFRm3hrEditFieldLabel.Text = 'VFR (m^3/hr)';
            app.VFREditField = uieditfield(app.MainTab, 'numeric');
            app.VFREditField.ValueChangedFcn = createCallbackFcn(app, @VFREditFieldValueChanged, true);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.UITable = uitable(app.DataTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = reducer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end